classdef Offline_Equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileParametersPanel         matlab.ui.container.Panel
        RunTime                     matlab.ui.control.NumericEditField
        timesEditFieldLabel_3       matlab.ui.control.Label
        SampleFreq                  matlab.ui.control.NumericEditField
        FsLabel                     matlab.ui.control.Label
        EffectsPanel                matlab.ui.container.Panel
        FreqShiftHzEditField        matlab.ui.control.NumericEditField
        FreqShiftHzEditFieldLabel   matlab.ui.control.Label
        FreqShiftButton             matlab.ui.control.StateButton
        EchoDelaysEditField         matlab.ui.control.NumericEditField
        EchoDelaysEditFieldLabel    matlab.ui.control.Label
        ReverbButton                matlab.ui.control.StateButton
        EchoButton                  matlab.ui.control.StateButton
        DarrenAlejasEqualizerLabel  matlab.ui.control.Label
        PlayOutputButton            matlab.ui.control.Button
        InputGain                   matlab.ui.control.Knob
        VolumeLabel                 matlab.ui.control.Label
        EqualizerPanel              matlab.ui.container.Panel
        ResetEqualizerButton        matlab.ui.control.Button
        Gain6                       matlab.ui.control.Slider
        Gain5                       matlab.ui.control.Slider
        Gain4                       matlab.ui.control.Slider
        Gain3                       matlab.ui.control.Slider
        Gain2                       matlab.ui.control.Slider
        Gain1                       matlab.ui.control.Slider
        kHzSlider_4Label            matlab.ui.control.Label
        kHzSlider_3Label            matlab.ui.control.Label
        kHzSlider_2Label            matlab.ui.control.Label
        kHzLabel                    matlab.ui.control.Label
        HzSlider_2Label             matlab.ui.control.Label
        HzSliderLabel               matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
        PlayInputButton             matlab.ui.control.Button
        LoadAudioPanel              matlab.ui.container.Panel
        SaveAudioLabel              matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        FilenameEditField_2         matlab.ui.control.EditField
        BrowseButton                matlab.ui.control.Button
        LoadAudioEditField          matlab.ui.control.EditField
        LoadAudioEditFieldLabel     matlab.ui.control.Label
        EQresponse                  matlab.ui.control.UIAxes
        Input_time                  matlab.ui.control.UIAxes
        Input_freq                  matlab.ui.control.UIAxes
        output_time                 matlab.ui.control.UIAxes
        Output_freq                 matlab.ui.control.UIAxes
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end

    
    properties (Access = private)
        % Done by Darren Aleja
        x_raw                 % Raw audio input without input gain
        x                     % Input signal with input gain

        Fs = 44100            % Sampling frequency of input signal assuming waveform audio

        output                % Post-processed time-domain signal

        % Band 1: Low-pass spec
        filterSpec1
        % Band 2: Band-pass spec
        filterSpec2
        % Band 3: Band-pass spec
        filterSpec3
        % Band 4: Band-pass spec
        filterSpec4
        % Band 5: Band-pass spec
        filterSpec5
        % Band 6: High-pass spec
        filterSpec6 

        FullFilePath % Stores the full path of the selected file

    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function updateEqualizerFilter(app)
            %% Note All Filters are Order N = 20
            % The Low-pass filter is Butterworth
            % The Band-pass filters are Chebychev type II.
            % The High-pass filter is Chebychev type II.
            % The Stopband attenuation is 80 dB.
            % All filters are designed in filter designer.
            % -------------------------------------------------------------
            % Design Notes:
            % For bass frequencies, we want a gradual roll-off due to the
            % lower frequencies having higher energy
            % I used butterworth as it has a natural roll off, a sharp
            % roll-off might make the bass sound too distorted.
            % Also I did not use ChebyII for this filter as there is a
            % notch due to the nature of its roll off and it would cause
            % certain frequencies to not have gain.
            % -------------------------------------------------------------
            % For the bandpass filters, I went with chebychev type II due
            % to its roll-off being sharper than butterworth with the same
            % order. I don't want the band to attenuate too large of a
            % frequency range. -Darren Aleja
            
            Nbutter = 50;
            Nchev = 60;        % Order chev
            Astop = 80;    % Stopband Attenuation (dB)
            
            %% Band1: Low-shelf filter for 0-80Hz 
            % Cut-off frequency = 80 Hz
            h1 = fdesign.lowpass('N,F3dB',Nbutter,80,app.Fs);
            app.filterSpec1 = design(h1, 'butter');

            %% Band2: Band-pass filter for 80-200Hz 
            % First Stopband Frequency = 80 Hz
            % Second Stopband Frequency = 200 Hz
            h2  = fdesign.bandpass('N,Fst1,Fst2,Ast', Nchev, 80, 200, Astop, app.Fs);
            app.filterSpec2 = design(h2, 'cheby2');

            %% Band3: Band-pass filter for 200-1000Hz 
            % First Stopband Frequency = 200 Hz
            % Second Stopband Frequency = 1000 Hz
            h3  = fdesign.bandpass('N,Fst1,Fst2,Ast', Nchev, 200, 1000, Astop, app.Fs);
            app.filterSpec3 = design(h3, 'cheby2');

            %% Band4: Band-pass filter for 1-3kHz 
            % First Stopband Frequency = 1000 Hz
            % Second Stopband Frequency = 3000 Hz
            h4  = fdesign.bandpass('N,Fst1,Fst2,Ast', Nchev, 1000, 3000, Astop, app.Fs);
            app.filterSpec4 = design(h4, 'cheby2');

            %% Band5: Band-pass filter for 3-8kHz 
            % First Stopband Frequency = 3000 Hz
            % Second Stopband Frequency = 8000 Hz
            h5  = fdesign.bandpass('N,Fst1,Fst2,Ast', Nchev, 3000, 8000, Astop, app.Fs);
            app.filterSpec5 = design(h5, 'cheby2');

            %% Band6: High-pass for 8-12kHz
            % First Stopband Frequency = 8000 Hz
            % Second Stopband Frequency = 12000 Hz
            h6  = fdesign.highpass('N,Fst,Ast', Nchev, 8000, Astop, app.Fs);
            app.filterSpec6 = design(h6, 'cheby2');
 
            %% Update Magnitude Plot
            
            % Obtain the frequency response
            [Darren1,w] = freqz(app.filterSpec1);
            Darren2 =  freqz(app.filterSpec2);
            Darren3 =  freqz(app.filterSpec3);
            Darren4 =  freqz(app.filterSpec4);
            Darren5 =  freqz(app.filterSpec5);
            Darren6 =  freqz(app.filterSpec6);

            % Take the real (magnitude) values of the frequencies.
            Darren1 = abs(Darren1);
            Darren2 = abs(Darren2);
            Darren3 = abs(Darren3);
            Darren4 = abs(Darren4);
            Darren5 = abs(Darren5);
            Darren6 = abs(Darren6);

            % Apply gain to each frequency response band
            Darren1 = Darren1.*app.Gain1.Value;
            Darren2 = Darren2.*app.Gain2.Value;
            Darren3 = Darren3.*app.Gain3.Value;
            Darren4 = Darren4.*app.Gain4.Value;
            Darren5 = Darren5.*app.Gain5.Value;
            Darren6 = Darren6.*app.Gain6.Value;

            % Add all frequency responses together
            Aleja = Darren1+Darren2+Darren3+Darren4+Darren5+Darren6;

            % Plot the EQ response
            plot(app.EQresponse,w,Aleja);
        end
        
        function updateInputPlot(app)
            % Linspace for time-domain plot
            t = linspace(0,length(app.x)/app.Fs,length(app.x)); 
            N = length(app.x); % Length of the signal

            % Plot input time plot
            plot(app.Input_time,t,app.x) 

            % Getting frequency spectrum
            X = fft(app.x);
            F = linspace(0, app.Fs/2, (N/2) +1);
            Amp = 2*abs(X(1:N/2 + 1))/N;

            % Plot the single-sided spectrum
            plot(app.Input_freq, F, Amp);
        end
        
        function updateOutputPlot(app)

            % Linspace for time-domain plot
            t = linspace(0,length(app.output)/app.Fs,length(app.output));
            N = length(app.output); % Length of the signal

            % Plot output time plot
            plot(app.output_time,t,app.output);

            % Getting frequency spectrum
            Output = fft(app.output);
            F = linspace(0, app.Fs/2, (N/2) +1);
            Amp = 2*abs(Output(1:N/2 + 1))/N;

            % Plot the single-sided spectrum
            plot(app.Output_freq, F, Amp);
       
        end

        function applyEQ(app)
             % Apply Band1 Filter:
             filter1 = filter(app.filterSpec1,app.x);
             filter1 = filter1*db2mag(app.Gain1.Value);

             % Apply Band2 Filter:
             filter2 = filter(app.filterSpec2,app.x);
             filter2 = filter2*db2mag(app.Gain2.Value);

             % Apply Band3 Filter:
             filter3 = filter(app.filterSpec3,app.x);
             filter3 = filter3*db2mag(app.Gain3.Value);

             % Apply Band4 Filter:
             filter4 = filter(app.filterSpec4,app.x);
             filter4 = filter4*db2mag(app.Gain4.Value);

             % Apply Band5 Filter:
             filter5 = filter(app.filterSpec5,app.x);
             filter5 = filter5*db2mag(app.Gain5.Value);

             % Apply Band6 Filter:
             filter6 = filter(app.filterSpec6,app.x);
             filter6 = filter6*db2mag(app.Gain6.Value);
           
             app.output = filter1 + filter2 + filter3 + filter4 + filter5 + filter6;
        end
        
        function applyReverb(app)
           % Reverb is a convolution technique where the impulse response
           % of an environment is recorded. This could be a empty hall or
           % lets say a nuclear reactor.
           % This .wav file is the impulse response of such environment.
           % Thus the IR signal and the input signal will be convoluted to
           % produce the reverb effect.
          
          
               % Load IR: This is an impulse response of a balloon popping in a hallway
               ir = audioread('Hall_IR.wav');
               % In-case of 2D-audio signal, convert to 1D
               ir = ir(:,1);
               % PLEASE DONT BLOW UP MY EARBALLS (reduce by -25 dB)
               ir = db2mag(-25)*ir;

               %Apply Reverb by convoluting the signal with the IR signal
               app.output = conv(app.output,ir); 
        end


        function applyFreqShift(app)
             input = app.output;
             % x is the input signal
             % Fs is the sampling frequency (44100 Hz)
             % shiftAmount is the frequency shift in Hz

             shiftAmount = app.FreqShiftHzEditField.Value;
    
             % Time vector
             t = (0:length(input)-1)/app.Fs;

             % Frequency shift in radians per second
             omega0 = 2*pi*shiftAmount;

             % Construct an FDESIGN object and call its CHEBY2 method.
             h  = fdesign.highpass('N,Fst,Ast', 20, shiftAmount, 80, app.Fs);
             Hd = design(h, 'cheby2');

             % Multiply the signal by the complex exponential
             app.output = mean(input) + filter(Hd, real((input - mean(input)) .* exp(1i*omega0*t).')); 
         end

        
        function applyEcho(app)

 
            % Get a new sample rate from time delay
            NewFs = round(app.Fs*app.EchoDelaysEditField.Value);

            % Pass non-echo part with new sample rate
            normalsignal = [app.output; zeros(NewFs,1)];
   
            % Apply time delay to echo part
            echo = [zeros(NewFs,1); app.output];

            % Add the normal signal with the echoed part
            echosignal = normalsignal + echo.*0.5;
    
            % Return the maximum elements in the array
            norm_factor = max(abs(echosignal));
    
            if norm_factor > 1
                echosignal = echosignal./norm_factor;
            end

            app.output = echosignal;
        end

        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.EchoDelaysEditField.Value =0.5;

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)

            % Get user to browse audio file
            [filename, filepath] = uigetfile({'*.wav';'*.mp3';'*.m4a';'*.*'});

            % Check if user canceled the file selection
            if isequal(filename, 0)
                disp('User canceled the file selection.');
                return;  % Exit the function if no file is selected
            end

            % Store the full file path in the private property
            app.FullFilePath = fullfile(filepath, filename);

            % Display filename in GUI
            app.LoadAudioEditField.Value = filename;

            figure(app.UIFigure);
            
            % Read audio file and add to private variables
            [app.x_raw,app.Fs] = audioread(app.FullFilePath);

            % Get audio file information for duration
            fileInfo = audioinfo(app.FullFilePath);
            durationInSeconds = fileInfo.Duration;  % Duration in seconds
        
            % Update the RunTime numeric edit field with the duration in seconds
            app.RunTime.Value = durationInSeconds;

            % In-case of 2D-audio signal, convert to 1D
            app.x_raw = app.x_raw(:,1);

            % Apply Gain Value x_raw -> gain -> x
            app.InputGainValueChanged(event);

            %Set the output with input as place holder
            app.output = app.x;

            % Show Plots
            app.updateInputPlot;
            app.updateOutputPlot;

            % Plot Fs in Text Fields
            app.SampleFreq.Value = app.Fs;
            
        end

        % Button pushed function: PlayInputButton
        function PlayInputButtonPushed(app, event)
            clear sound;
            %Play imported audio post input gain
            sound(app.x,app.Fs);
        end

        % Value changed function: InputGain
        function InputGainValueChanged(app, event)
           
            % Apply input gain to pre-gain signal
            app.x = db2mag(app.InputGain.Value)*app.x_raw;

            % Update input plots
            app.updateInputPlot;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: ResetEqualizerButton
        function ResetEqualizerButtonPushed(app, event)
            %Set all band gains to zero
            app.Gain1.Value = 0;
            app.Gain2.Value = 0;
            app.Gain3.Value = 0;
            app.Gain4.Value = 0;
            app.Gain5.Value = 0;
            app.Gain6.Value = 0;
            app.EqualizerValueChanged(event);
        end

        % Value changed function: Gain1, Gain2, Gain3, Gain4, Gain5, Gain6
        function EqualizerValueChanged(app, event)
            %Apply filter and update its plot
            app.updateEqualizerFilter;
        end

        % Button pushed function: PlayOutputButton
        function PlayOutputButtonPushed(app, event)
            % Clear any sound to avoid spamming
            clear sound;

            %Check if all gain values
            DJ = app.Gain1.Value+app.Gain2.Value+app.Gain3.Value+app.Gain4.Value+app.Gain5.Value+app.Gain6.Value;
            
            % Apply the EQ Filters if one gain is applied
            if DJ~=0
                app.applyEQ;
            else
                % Get fresh output in case EQ not active
                app.output = app.x;
            end

            % Apply Effects
            if app.EchoButton.Value==1
                app.applyEcho;
            end
            
            if app.ReverbButton.Value==1
                app.applyReverb;
            end

            if app.FreqShiftButton.Value==1
                app.applyFreqShift;
            end

            % Play post-processed audio
            sound(app.output,app.Fs);

            %Display Output Plots
            app.updateOutputPlot;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Get the full original filename (including path)
            fullOriginalFilename = app.FullFilePath;
        
            % Extract the base name (without extension) and the path
            [path, name, ~] = fileparts(fullOriginalFilename);
        
            % Construct the new filename with '_PROCESSED'
            processedFilename = [name '_PROCESSED.wav'];  % Just the filename, without path
            newFilename = fullfile(path, processedFilename);
        
            try
                audiowrite(newFilename, app.output, app.Fs);
                message = processedFilename;  % Use processedFilename here
            catch ME
                message = ['Error saving audio: ', ME.message];
            end
        
            % Display the message in the FilenameEditField_2 box
            app.FilenameEditField_2.Value = message;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Output_freq = uiaxes(app.UIFigure);
            title(app.Output_freq, 'Output Freq')
            xlabel(app.Output_freq, 'frequency (Hz)')
            ylabel(app.Output_freq, 'Amplitude')
            zlabel(app.Output_freq, 'Z')
            app.Output_freq.XLim = [0 12000];
            colormap(app.Output_freq, 'cool')
            app.output_time = uiaxes(app.UIFigure);
            title(app.output_time, 'Processed Time')
            xlabel(app.output_time, 'Time (s)')
            ylabel(app.output_time, 'Amplitude')
            zlabel(app.output_time, 'Z')
            app.output_time.YLim = [-0.5 0.5];
            colormap(app.output_time, 'cool')
            app.Input_freq = uiaxes(app.UIFigure);
            title(app.Input_freq, 'Imported Freq')
            xlabel(app.Input_freq, 'frequency (Hz)')
            ylabel(app.Input_freq, 'Amplitude')
            zlabel(app.Input_freq, 'Z')
            app.Input_freq.XLim = [0 12000];
            app.Input_time = uiaxes(app.UIFigure);
            title(app.Input_time, 'Imported Signal ')
            xlabel(app.Input_time, 'Time (s)')
            ylabel(app.Input_time, 'Amplitude')
            zlabel(app.Input_time, 'Z')
            app.Input_time.YLim = [-0.5 0.5];
            app.EQresponse = uiaxes(app.UIFigure);
            title(app.EQresponse, 'EQ Magnitude Response w/ Gain Factor')
            xlabel(app.EQresponse, 'Normalized Frequency (x \pi rad/sample)')
            ylabel(app.EQresponse, 'Gain (dB)')
            zlabel(app.EQresponse, 'Z')
            app.EQresponse.XLim = [0 3.1];
            app.EQresponse.YLim = [-60 60];
            app.EQresponse.XTick = [0 3.14];
            app.EQresponse.XScale = 'log';
            app.EQresponse.XTickLabel = '';
            app.LoadAudioPanel = uipanel(app.UIFigure);
            app.LoadAudioPanel.Title = 'Load Audio';
            app.LoadAudioEditFieldLabel = uilabel(app.LoadAudioPanel);
            app.LoadAudioEditFieldLabel.Text = 'Load Audio';
            app.LoadAudioEditField = uieditfield(app.LoadAudioPanel, 'text');
            app.BrowseButton = uibutton(app.LoadAudioPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FilenameEditField_2 = uieditfield(app.LoadAudioPanel, 'text');
            app.SaveButton = uibutton(app.LoadAudioPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SaveAudioLabel = uilabel(app.LoadAudioPanel);
            app.SaveAudioLabel.Text = 'Save Audio';
            app.PlayInputButton = uibutton(app.UIFigure, 'push');
            app.PlayInputButton.ButtonPushedFcn = createCallbackFcn(app, @PlayInputButtonPushed, true);
            app.PlayInputButton.Text = 'Play Input';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.EqualizerPanel = uipanel(app.UIFigure);
            app.EqualizerPanel.Title = 'Equalizer';
            app.HzSliderLabel = uilabel(app.EqualizerPanel);
            app.HzSliderLabel.Text = '0-80 Hz';
            app.HzSlider_2Label = uilabel(app.EqualizerPanel);
            app.HzSlider_2Label.Text = '80-200 Hz';
            app.kHzLabel = uilabel(app.EqualizerPanel);
            app.kHzLabel.Text = '200-1kHz';
            app.kHzSlider_2Label = uilabel(app.EqualizerPanel);
            app.kHzSlider_2Label.Text = '1-3 kHz';
            app.kHzSlider_3Label = uilabel(app.EqualizerPanel);
            app.kHzSlider_3Label.Text = '3-8 kHz';
            app.kHzSlider_4Label = uilabel(app.EqualizerPanel);
            app.kHzSlider_4Label.Text = '8-12 kHz';
            app.Gain1 = uislider(app.EqualizerPanel);
            app.Gain1.Limits = [-60 60];
            app.Gain1.MajorTicks = [-60 -30 0 30 60];
            app.Gain1.ValueChangedFcn = createCallbackFcn(app, @EqualizerValueChanged, true);
            app.Gain2 = uislider(app.EqualizerPanel);
            app.Gain2.Limits = [-60 60];
            app.Gain2.MajorTicks = [-60 -30 0 30 60];
            app.Gain2.ValueChangedFcn = createCallbackFcn(app, @EqualizerValueChanged, true);
            app.Gain3 = uislider(app.EqualizerPanel);
            app.Gain3.Limits = [-60 60];
            app.Gain3.MajorTicks = [-60 -30 0 30 60];
            app.Gain3.ValueChangedFcn = createCallbackFcn(app, @EqualizerValueChanged, true);
            app.Gain4 = uislider(app.EqualizerPanel);
            app.Gain4.Limits = [-60 60];
            app.Gain4.MajorTicks = [-60 -30 0 30 60];
            app.Gain4.ValueChangedFcn = createCallbackFcn(app, @EqualizerValueChanged, true);
            app.Gain5 = uislider(app.EqualizerPanel);
            app.Gain5.Limits = [-60 60];
            app.Gain5.MajorTicks = [-60 -30 0 30 60];
            app.Gain5.ValueChangedFcn = createCallbackFcn(app, @EqualizerValueChanged, true);
            app.Gain6 = uislider(app.EqualizerPanel);
            app.Gain6.Limits = [-60 60];
            app.Gain6.MajorTicks = [-60 -30 0 30 60];
            app.Gain6.ValueChangedFcn = createCallbackFcn(app, @EqualizerValueChanged, true);
            app.ResetEqualizerButton = uibutton(app.EqualizerPanel, 'push');
            app.ResetEqualizerButton.ButtonPushedFcn = createCallbackFcn(app, @ResetEqualizerButtonPushed, true);
            app.ResetEqualizerButton.Text = 'Reset Bands';
            app.VolumeLabel = uilabel(app.UIFigure);
            app.VolumeLabel.Text = 'Volume';
            app.InputGain = uiknob(app.UIFigure, 'continuous');
            app.InputGain.Limits = [-20 20];
            app.InputGain.MajorTicks = [-20 -10 0 10 20];
            app.InputGain.ValueChangedFcn = createCallbackFcn(app, @InputGainValueChanged, true);
            app.PlayOutputButton = uibutton(app.UIFigure, 'push');
            app.PlayOutputButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOutputButtonPushed, true);
            app.PlayOutputButton.Text = 'Play Output';
            app.DarrenAlejasEqualizerLabel = uilabel(app.UIFigure);
            app.DarrenAlejasEqualizerLabel.Text = 'Darren Aleja''s Equalizer';
            app.EffectsPanel = uipanel(app.UIFigure);
            app.EffectsPanel.Title = 'Effects';
            app.EchoButton = uibutton(app.EffectsPanel, 'state');
            app.EchoButton.Text = 'Echo';
            app.ReverbButton = uibutton(app.EffectsPanel, 'state');
            app.ReverbButton.Text = 'Reverb';
            app.EchoDelaysEditFieldLabel = uilabel(app.EffectsPanel);
            app.EchoDelaysEditFieldLabel.Text = 'Echo  Delay (s)';
            app.EchoDelaysEditField = uieditfield(app.EffectsPanel, 'numeric');
            app.FreqShiftButton = uibutton(app.EffectsPanel, 'state');
            app.FreqShiftButton.Text = 'Freq Shift';
            app.FreqShiftHzEditFieldLabel = uilabel(app.EffectsPanel);
            app.FreqShiftHzEditFieldLabel.Text = 'Freq Shift (Hz)';
            app.FreqShiftHzEditField = uieditfield(app.EffectsPanel, 'numeric');
            app.FileParametersPanel = uipanel(app.UIFigure);
            app.FileParametersPanel.Title = 'File Parameters';
            app.FsLabel = uilabel(app.FileParametersPanel);
            app.FsLabel.Text = 'Fs';
            app.SampleFreq = uieditfield(app.FileParametersPanel, 'numeric');
            app.SampleFreq.ValueDisplayFormat = '%.0f';
            app.timesEditFieldLabel_3 = uilabel(app.FileParametersPanel);
            app.timesEditFieldLabel_3.Text = 'time (s)';
            app.RunTime = uieditfield(app.FileParametersPanel, 'numeric');
            app.RunTime.ValueDisplayFormat = '%.3f';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Offline_Equalizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end